classdef ContentScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        BackButton             matlab.ui.container.toolbar.PushTool
        Panel                  matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        TrendoftheVariableTab  matlab.ui.container.Tab
        Plot1                  matlab.ui.control.UIAxes
        SummaryLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
        
            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
        
            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;
        
            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;
            
            app.UIFigure.Name = 'CMIP6 Climate Projections Visualizer';
        end

        % Callback function: BackButton
        function BackButtonClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.BackButton = uipushtool(app.Toolbar);
            app.BackButton.Tooltip = {'Return to the previous screen'};
            app.BackButton.ClickedCallback = createCallbackFcn(app, @BackButtonClicked, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'res', 'back_button.png');
            app.Panel = uipanel(app.UIFigure);
            app.SummaryLabel = uilabel(app.Panel);
            app.SummaryLabel.Text = {'xxx'; 'xxx'; 'xxx'};
            app.TabGroup = uitabgroup(app.Panel);
            app.TrendoftheVariableTab = uitab(app.TabGroup);
            app.TrendoftheVariableTab.Tooltip = {''};
            app.TrendoftheVariableTab.Title = 'Trend of the Variable';
            app.Plot1 = uiaxes(app.TrendoftheVariableTab);
            title(app.Plot1, 'Title')
            xlabel(app.Plot1, 'x')
            ylabel(app.Plot1, 'y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContentScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end