classdef WelcomeScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        VisualizeButton        matlab.ui.control.Button
        ModelButtonGroup       matlab.ui.container.ButtonGroup
        ECEarth3CCButton_2     matlab.ui.control.RadioButton
        ECEarth3CCButton       matlab.ui.control.RadioButton
        Label                  matlab.ui.control.Label
        Spinner_2              matlab.ui.control.Spinner
        Label0_2               matlab.ui.control.Label
        Spinner                matlab.ui.control.Spinner
        ExperimentButtonGroup  matlab.ui.container.ButtonGroup
        SSP585Button           matlab.ui.control.RadioButton
        SSP460Button           matlab.ui.control.RadioButton
        SSP534OSButton_2       matlab.ui.control.RadioButton
        SSP126Button_2         matlab.ui.control.RadioButton
        SSP119Button           matlab.ui.control.RadioButton
        SSP434Button_2         matlab.ui.control.RadioButton
        SSP245Button           matlab.ui.control.RadioButton
        SSP370Button           matlab.ui.control.RadioButton
        Label0                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
        
            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
        
            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;
        
            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;
            
            app.UIFigure.Name = 'Sea ​​Surface Temperature Calculator';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Label0 = uilabel(app.Panel);
            app.Label0.Text = 'CMIP6 Climate Projections Visualizer';
            app.ExperimentButtonGroup = uibuttongroup(app.Panel);
            app.ExperimentButtonGroup.Title = 'Experiment';
            app.SSP370Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP370Button.Text = 'SSP3-7.0';
            app.SSP245Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP245Button.Text = 'SSP2-4.5';
            app.SSP434Button_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP434Button_2.Text = 'SSP4-3.4';
            app.SSP119Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP119Button.Text = 'SSP1-1.9';
            app.SSP119Button.Value = true;
            app.SSP126Button_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP126Button_2.Text = 'SSP1-2.6';
            app.SSP534OSButton_2 = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP534OSButton_2.Text = 'SSP5-3.4OS';
            app.SSP460Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP460Button.Text = 'SSP4-6.0';
            app.SSP585Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP585Button.Text = 'SSP5-8.5';
            app.Spinner = uispinner(app.Panel);
            app.Spinner.Limits = [2016 2100];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Value = 2016;
            app.Label0_2 = uilabel(app.Panel);
            app.Label0_2.Text = 'Year';
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_2.Limits = [2016 2100];
            app.Spinner_2.ValueDisplayFormat = '%.0f';
            app.Spinner_2.Value = 2024;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '-';
            app.ModelButtonGroup = uibuttongroup(app.Panel);
            app.ModelButtonGroup.Title = 'Model';
            app.ECEarth3CCButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3CCButton.Text = 'EC-Earth3-CC';
            app.ECEarth3CCButton.Value = true;
            app.ECEarth3CCButton_2 = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3CCButton_2.Text = 'EC-Earth3-CC';
            app.VisualizeButton = uibutton(app.Panel, 'push');
            app.VisualizeButton.Icon = fullfile(pathToMLAPP, 'res', 'diagram_icon.png');
            app.VisualizeButton.Text = 'Visualize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WelcomeScreen

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end